classdef GUI_try < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ParametersTab                 matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        EarliestactivatedleadratioSlider  matlab.ui.control.Slider
        EarliestactivatedleadratioSliderLabel  matlab.ui.control.Label
        NotificationTextArea          matlab.ui.control.TextArea
        GeometryexistsCheckBox        matlab.ui.control.CheckBox
        ATisnotprecomputedCheckBox    matlab.ui.control.CheckBox
        ATMethodsDropDown             matlab.ui.control.DropDown
        ATMethodsDropDownLabel        matlab.ui.control.Label
        SindexTextArea                matlab.ui.control.TextArea
        SindexTextAreaLabel           matlab.ui.control.Label
        QindexTextArea                matlab.ui.control.TextArea
        QindexTextAreaLabel           matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SamplingFrequencyHzTextArea   matlab.ui.control.TextArea
        SamplingFrequencyHzLabel      matlab.ui.control.Label
        EpicardialPotentialsDropDown  matlab.ui.control.DropDown
        EpicardialPotentialsDropDownLabel  matlab.ui.control.Label
        ATDropDown                    matlab.ui.control.DropDown
        ATDropDownLabel               matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        TemporalVisualizationTab      matlab.ui.container.Tab
        OverwriteButton               matlab.ui.control.Button
        MaponGeometryButton           matlab.ui.control.Button
        NotificationsTextArea         matlab.ui.control.TextArea
        ButtonGroup                   matlab.ui.container.ButtonGroup
        DefectiveButton               matlab.ui.control.RadioButton
        SaveButton                    matlab.ui.control.RadioButton
        ShowButton                    matlab.ui.control.RadioButton
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        SpatialVisualizationTab       matlab.ui.container.Tab
        OverwriteButton_2             matlab.ui.control.Button
        NodeNumberTextArea            matlab.ui.control.TextArea
        NodeNumberTextAreaLabel       matlab.ui.control.Label
        PlotButton_2                  matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dict % loaded file is converted dict
        path % file path
          AT % AT values from data
   Loaded_AT % AT values from data
     valueAT % ATDropDown value
  user_ATGUI % saved AT values from plot
     potvals % potential values from data
    sampFreq % sampling frequency of low pass filter
         fig % UIFigure
         plt % plot of potential values
    QRSBegin % QRSBegin value provided by user
      QRSEnd % QRSBegin value provided by user
           d % datacursor mode
       check % check whether the AT is proper form
AT_not_exist_val % true if AT does not exist
    filename %
    percent  % percentage of AT
   diff_potv % first order derivative of potvals
   nodenum
   geom_file
        geom % geometry 
        PTS
        FAC
    Badlead  % Badlead array
xline_handle 
         L
    ATMethod
    tg
    end
    
    methods (Access = public)
        
          function h = map_voltage_on_geom_app(app,PTS,FAC,Data, title_char_vec, PaceLoc)
                
                set(0,'defaultFigureVisible','off')
                x = PTS(:,1);
                y = PTS(:,2);
                z = PTS(:,3);
                h = trisurf(FAC,x,y,z,Data,'Parent',app.UIAxes3);
                set(h,'Facecolor','Interp','EdgeColor','Interp')
                xticks(app.UIAxes3,[])
                yticks(app.UIAxes3,[])
                zticks(app.UIAxes3,[])
                grid (app.UIAxes3,'off')
                ax = gca;
                axis(ax,'off');
                hold(app.UIAxes3,"on");
            
                if nargin > 5
                     hpaceloc = scatter3(x(PaceLoc),y(PaceLoc),z(PaceLoc),100,'yellow','filled','Parent',app.UIAxes3);
                end
                
                legend(hpaceloc,'Pacing Location')

                datatip(h,0,0,0);
                row =  dataTipTextRow('Node #',FAC');
                h.DataTipTemplate.DataTipRows(end+1) = row;

                DataMapping = arrayfun(@(x) Data(x), FAC);
                row =  dataTipTextRow('AT =',DataMapping');
                h.DataTipTemplate.DataTipRows(end+1) = row;
            
                colormap(app.UIAxes3,'jet')
                title(app.UIAxes3,title_char_vec)
                set(0,'defaultFigureVisible','on')
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes3,"off")
            app.ATMethod = "Modified";
            app.tg = matlab.ui.container.TabGroup;
            app.TabGroup.Children(3).Parent = app.tg;
            addpath('functions');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                
              
                % load selected file 
                [app.filename,app.path] = uigetfile('*.mat', 'Select a File');
                if isequal(app.filename,0)
                    disp('User selected Cancel')
                else
                    app.L = load(fullfile(app.path,app.filename));
                end

                % Make component enable false
                app.ATDropDown.Enable = false;
                app.MaponGeometryButton.Enable = false;
                app.ATisnotprecomputedCheckBox.Enable = false;
                app.ATMethodsDropDown.Enable = false;
           
                % Load the file as dictionary
                app.dict = load_as_dict(app.L);
                app.dict("--None--") = {NaN};
               
                app.ATDropDown.Items = app.dict.keys; % items of dropdown is keys of dict
                app.ATDropDown.Value = "--None--";
                app.EpicardialPotentialsDropDown.Items = app.dict.keys;
                app.EpicardialPotentialsDropDown.Value = "--None--";
                
                app.NotificationTextArea.Value = "The file has been uploaded. Choose epicardial potentials, Q and S indices.";
                value= app.SamplingFrequencyHzTextArea.Value;
                app.sampFreq = str2double(value);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % delete datatip
            dt = findobj(app.UIAxes,"Type","DataTip");
            delete(dt)
            app.TabGroup.SelectedTab = app.TemporalVisualizationTab; % open second tab automatically 
            
            % Create Badlead array
            app.Badlead = [];
            
            % Taking the first derivative of potvals
            x = lowpass(app.potvals',app.sampFreq/2.1,app.sampFreq)';
            app.diff_potv = diff(x')';
            
            if app.check == 1

              app.user_ATGUI = app.Loaded_AT-app.QRSBegin; %make a copy of AT 
              [sorted_AT,idx] = sort(app.AT); %sorting AT values
              M = length(sorted_AT)*app.percent;
              j = 1;
               while j <= M 

                    app.fig = figure(app.UIFigure);
                    
                    % Plot the potvals corresponding to sorted AT, respectively.
                    app.plt = plot(app.UIAxes2,app.potvals(idx(j),:));
                    hold(app.UIAxes2,"on")
                    % Plot the QRS region
                    h_QRS = plot(app.UIAxes2,app.QRSBegin:app.QRSEnd,app.potvals(idx(j),app.QRSBegin:app.QRSEnd),"LineWidth",2,"Color","red");
                    legend(app.UIAxes2,h_QRS,"QRS Segment")
                    title(app.UIAxes2,"Heart potentials:",['Lead index:',num2str(idx(j))])
                    hold(app.UIAxes2,"off")
                    
                    % Plot the first order derivative in QRS region
                    plt1 = plot(app.UIAxes2_2,app.QRSBegin:app.QRSEnd,app.diff_potv(idx(j),app.QRSBegin:app.QRSEnd));
                    title(app.UIAxes2_2,"1^{st} order derivative in QRS segment:",['Lead index:',num2str(idx(j))])
                    datatip(plt1,"DataIndex",app.user_ATGUI(idx(j))-1);
    
                    if (app.ShowButton.Value == 1)
    
                        while true
                            %show the datatip selected in the derivative of potvals
                            %on potval plot
                            datacursormode (app.UIFigure, 'on');
                            dcm_obj = datacursormode(app.fig);
                            set(dcm_obj,'UpdateFcn',@myupdatefcn)
                            app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                            pause 
                            info_struct_1 = getCursorInfo(dcm_obj);
                            info_struct = info_struct_1(1);
                            DataIndex = info_struct.DataIndex;
                            dcm_obj.removeAllDataCursors();

                            % Show a line of selected AT value
                            datatip(app.plt,"DataIndex",DataIndex+app.QRSBegin)                            
                            delete(app.xline_handle)
                            app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');

                            if (app.SaveButton.Value == 1)
                                app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                                % Subtract the QRSbegin from the AT values, if the
                                % AT values are in the QRS region
                                corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                app.user_ATGUI(idx(j)) = corrector_AT;
                                j= j+1;                          
                                break

                            elseif(app.DefectiveButton.Value == 1)
                                app.user_ATGUI(idx(j)) = NaN;
                                app.Badlead(end+1) = idx(j);
                                M = M + 1;   
                                j = j + 1;
                                break
                            else 
                                continue
                            end
                         end
                        
    
                    elseif (app.SaveButton.Value == 1)

                        %save selected datatip
                        datacursormode (app.UIFigure, 'on')
                        dcm_obj = datacursormode(app.fig);

                        % Set update function
                        set(dcm_obj,'UpdateFcn',@myupdatefcn)
                        app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                        pause 
                        
                        if (app.ShowButton.Value == 1)
    
                            while true
                                %show the datatip selected in the derivative of potvals
                                %on potval plot
                                datacursormode (app.UIFigure, 'on')
                                dcm_obj = datacursormode(app.fig);
                                set(dcm_obj,'UpdateFcn',@myupdatefcn)
                                app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                                pause 
                                info_struct_1 = getCursorInfo(dcm_obj);
                                info_struct = info_struct_1(1);
                                dcm_obj.removeAllDataCursors();
                                DataIndex = info_struct.DataIndex;
                                dcm_obj.removeAllDataCursors();

                                % Show a line of selected AT value
                                datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                                delete(app.xline_handle)
                                app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                                
                                if (app.SaveButton.Value == 1)
                                    app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                                    corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                    app.user_ATGUI(idx(j)) = corrector_AT;
                                    j= j+1;
                                    break
                                    
                                elseif(app.DefectiveButton.Value == 1)
                                    app.user_ATGUI(idx(j)) = NaN;
                                    app.Badlead(end+1) = idx(j);
                                    M = M + 1;
                                    j= j+1;
                                    break
                                else 
                                    continue
                                end
                            end
                         elseif(app.DefectiveButton.Value == 1)
                     
                            pause
                            
    
                        else
                            info_struct_1 = getCursorInfo(dcm_obj);
                            info_struct = info_struct_1(1);
                            dcm_obj.removeAllDataCursors();
                            DataIndex = info_struct.DataIndex;
                            corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                            app.user_ATGUI(idx(j)) = corrector_AT;
                            j= j+1;    
                        end

                     elseif (app.DefectiveButton.Value == 1)

                        datacursormode (app.UIFigure, 'on')
                        dcm_obj = datacursormode(app.fig);
                  
                        set(dcm_obj,'UpdateFcn',@myupdatefcn)
                  
                        pause 
                        
                        if (app.ShowButton.Value == 1)

                            while true
                                %show the datatip selected in the derivative of potvals
                                %on potval plot
                                datacursormode (app.UIFigure, 'on')
                                dcm_obj = datacursormode(app.fig);
                                set(dcm_obj,'UpdateFcn',@myupdatefcn)
                                %disp('Click line to display a data tip, then press "Return"')
                                pause 
                                info_struct_1 = getCursorInfo(dcm_obj);
                                info_struct = info_struct_1(1);
                                DataIndex = info_struct.DataIndex;
                                dcm_obj.removeAllDataCursors();
                                datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                                delete(app.xline_handle)
                                app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                                if (app.SaveButton.Value == 1)
                                    corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                    app.user_ATGUI(idx(j)) = corrector_AT;
                                    j = j + 1;
                                    break
                                elseif(app.DefectiveButton.Value == 1)
                                    app.user_ATGUI(idx(j)) = NaN;
                                    app.Badlead(end+1) = idx(j);
                                    M = M + 1;
                                    j= j+1;
                                    break
                                else 
                                    continue
                                end
                             end
                        elseif(app.SaveButton.Value)
                        
                            pause
                

                        else
                            app.user_ATGUI(idx(j)) = NaN;
                            app.Badlead(end+1) = idx(j);
                            M = M + 1;
                            j= j+1;
                        end
                     end
                end

                userATGUI = saveobj(app.user_ATGUI);
                save(fullfile(app.path, 'user_ATGUI'), 'userATGUI');
                app.dict("User_ATGUI") = num2cell(app.user_ATGUI,[1 2]);
                dictn = saveobj(app.dict);
                save(fullfile(app.path, 'dict'), 'dictn');
                app.NotificationsTextArea.Value = "If you want to overwrite the AT values, push the 'overwrite' button!";
                badlead = saveobj(app.Badlead);
                save(fullfile(app.path, 'badlead'), 'badlead');
            else
                
                switch app.ATMethodsDropDown.Value
                    case "min dV/dt"
                        
                        app.ATMethod = "mindvdt";
                        potvals_2 = app.potvals(:,app.QRSBegin:app.QRSEnd); % potvals QRSbegin and QRSend substracted
                        ATmindVdT = mindVdT(potvals_2);
                        AT_calculated = ATmindVdT + app.QRSBegin; %calculated AT
                    case "Spatially Coherent"
                        app.ATMethod = "Spatially_Coherent";
                        potvals_2 = app.potvals(:,app.QRSBegin:app.QRSEnd);
                        SPCoh_AT = SpCoherentActTime(potvals_2, app.geom);
                        SPCoh_AT = round(SPCoh_AT);
                        AT_calculated = SPCoh_AT + app.QRSBegin; 
                    case "Spatio-Temporal"

                        app.ATMethod = "Spatio_Temporal";
                        potvals_2 = app.potvals(:,app.QRSBegin:app.QRSEnd);
                        L_geom = surface_laplacian(app.geom);
                        TActivation = ActivationTimeST(potvals_2, L_geom);
                        TActivation = round(TActivation);
                        AT_calculated = TActivation + app.QRSBegin; 
                end

                  app.user_ATGUI = AT_calculated-app.QRSBegin; %make a copy of AT 
                  [sorted_AT,idx] = sort(AT_calculated); %sorting AT values
                  M = length(sorted_AT)*app.percent;
                  j = 1;
                  while j <= M 
    
                        app.fig = figure(app.UIFigure);
        
                        app.plt = plot(app.UIAxes2,app.potvals(idx(j),:));
                        hold(app.UIAxes2,"on")
                        h_QRS = plot(app.UIAxes2,app.QRSBegin:app.QRSEnd,app.potvals(idx(j),app.QRSBegin:app.QRSEnd),"LineWidth",2,"Color","red");
                        legend(app.UIAxes2,h_QRS,"QRS Segment")
                        title(app.UIAxes2,"Heart potentials:",['Lead index:',num2str(idx(j))])
                        hold(app.UIAxes2,"off")
    
                        plt1 = plot(app.UIAxes2_2,app.QRSBegin:app.QRSEnd,app.diff_potv(idx(j),app.QRSBegin:app.QRSEnd));
                        title(app.UIAxes2_2,"1^{st} order derivative in QRS segment:",['Lead index:',num2str(idx(j))])
                        datatip(plt1,"DataIndex",app.user_ATGUI(idx(j)-1));
        
                        if (app.ShowButton.Value == 1)
        
                            while true
                                %show the datatip selected in the derivative of potvals
                                %on potval plot
                                datacursormode (app.UIFigure, 'on');
                                dcm_obj = datacursormode(app.fig);
                                set(dcm_obj,'UpdateFcn',@myupdatefcn)
                                app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                                pause 
                                info_struct_1 = getCursorInfo(dcm_obj);
                                info_struct = info_struct_1(1);
                                DataIndex = info_struct.DataIndex;
                                dcm_obj.removeAllDataCursors();
                                datatip(app.plt,"DataIndex",DataIndex+app.QRSBegin)                            
                                delete(app.xline_handle)
                                app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                                if (app.SaveButton.Value == 1)
                                    app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                                    corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                    app.user_ATGUI(idx(j)) = corrector_AT;
                                    j= j+1;                          
                                    break
                                elseif(app.DefectiveButton.Value == 1)
                                    app.user_ATGUI(idx(j)) = NaN;
                                    app.Badlead(end+1) = idx(j);
                                    M = M + 1;   
                                    j = j + 1;
                                    break
                                else 
                                    continue
                                end
                             end
                            
        
                        elseif (app.SaveButton.Value == 1)
    
                            %save selected datatip
                            datacursormode (app.UIFigure, 'on')
                            dcm_obj = datacursormode(app.fig);
                            % Set update function
                            set(dcm_obj,'UpdateFcn',@myupdatefcn)
                            app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                            pause 
                            
                            if (app.ShowButton.Value == 1)
        
                                while true
                                    %show the datatip selected in the derivative of potvals
                                    %on potval plot
                                    datacursormode (app.UIFigure, 'on')
                                    dcm_obj = datacursormode(app.fig);
                                    set(dcm_obj,'UpdateFcn',@myupdatefcn)
                                    app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                                    pause 
                                    info_struct_1 = getCursorInfo(dcm_obj);
                                    info_struct = info_struct_1(1);
                                    dcm_obj.removeAllDataCursors();
                                    DataIndex = info_struct.DataIndex;
                                    dcm_obj.removeAllDataCursors();
                                    datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                                    delete(app.xline_handle)
                                    app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                                    if (app.SaveButton.Value == 1)
                                        app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                                        corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                        app.user_ATGUI(idx(j)) = corrector_AT;
                                        j = j + 1;
                                        break
                                    elseif(app.DefectiveButton.Value == 1)
                                        app.user_ATGUI(idx(j)) = NaN;
                                        app.Badlead(end+1) = idx(j);
                                        M = M + 1;
                                        j = j + 1;
                                        break
                                    else 
                                        continue
                                    end
                                end
                             elseif(app.DefectiveButton.Value == 1)
                         
                                pause
                                
        
                            else
                                info_struct_1 = getCursorInfo(dcm_obj);
                                info_struct = info_struct_1(1);
                                dcm_obj.removeAllDataCursors();
                                DataIndex = info_struct.DataIndex;
                                corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                app.user_ATGUI(idx(j)) = corrector_AT;
                                j = j + 1;    
                            end
    
                         elseif (app.DefectiveButton.Value == 1)
    
                            datacursormode (app.UIFigure, 'on')
                            dcm_obj = datacursormode(app.fig);
                      
                            set(dcm_obj,'UpdateFcn',@myupdatefcn)
                      
                            pause 
                            
                            if (app.ShowButton.Value == 1)
    
                                while true
                                    %show the datatip selected in the derivative of potvals
                                    %on potval plot
                                    datacursormode (app.UIFigure, 'on')
                                    dcm_obj = datacursormode(app.fig);
                                    set(dcm_obj,'UpdateFcn',@myupdatefcn)
                                    %disp('Click line to display a data tip, then press "Return"')
                                    pause 
                                    info_struct_1 = getCursorInfo(dcm_obj);
                                    info_struct = info_struct_1(1);
                                    DataIndex = info_struct.DataIndex;
                                    dcm_obj.removeAllDataCursors();
                                    datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                                    delete(app.xline_handle)
                                    app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                                    if (app.SaveButton.Value == 1)
                                        corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                                        app.user_ATGUI(idx(j)) = corrector_AT;
                                        j = j + 1;
                                        break
                                    elseif(app.DefectiveButton.Value == 1)
                                        app.user_ATGUI(idx(j)) = NaN;
                                        app.Badlead(end+1) = idx(j);
                                        M = M + 1;
                                        j = j + 1;
                                        break
                                    else 
                                        continue
                                    end
                                 end
                            elseif(app.SaveButton.Value)
                            
                                pause
                    
    
                            else
                                app.user_ATGUI(idx(j)) = NaN;
                                app.Badlead(end+1) = idx(j);
                                M = M + 1;
                                j = j + 1;
                            end
                         end
                   end
    
                badlead = saveobj(app.Badlead);
                save(fullfile(app.path, 'badlead'), 'badlead');

                userATGUI = saveobj(app.user_ATGUI);
                save(fullfile(app.path, 'user_ATGUI'), 'userATGUI');
                app.dict("User_ATGUI") = num2cell(app.user_ATGUI,[1 2]);
                dictn = saveobj(app.dict);
                save(fullfile(app.path, 'dict'), 'dictn');
                app.NotificationsTextArea.Value = "If you want to overwrite the AT values, push the 'overwrite' button!";
                
            end
        end

        % Value changed function: ATDropDown
        function ATDropDownValueChanged(app, event)
            app.valueAT = app.ATDropDown.Value;
            cell_AT = app.dict(app.valueAT);

            app.AT = cell_AT{1};
            % Checking proper AT values exist
            app.check = (sum(isnan(app.AT))==0) && (numel(app.AT)==size(app.potvals,1));
            
            % check_AT function: It checks if AT values are between the QRS region
            % or not. If not in the range, it adds the QRSBegin value       
            app.Loaded_AT = check_AT(app.QRSBegin,app.QRSEnd,app.AT);
            app.NotificationTextArea.Value = "If activation time is not precomputed, check the box";


        end

        % Value changed function: EpicardialPotentialsDropDown
        function EpicardialPotentialsDropDownValueChanged(app, event)

            value = app.EpicardialPotentialsDropDown.Value;
            cell_potvals = app.dict(value);
            app.potvals = cell_potvals{1};
            app.user_ATGUI = zeros(size(app.potvals,1),1);

            app.NotificationTextArea.Value = "Check the boxes, if AT does not exist in the file and if the geom exists.";
            
            cla(app.UIAxes)
            h1 = plot(app.UIAxes,app.potvals');
            hold(app.UIAxes,"on")
            h2 = plot(app.UIAxes,rms(app.potvals),"LineWidth",3,"Color","y");
            title(app.UIAxes,app.filename(1:end-4),"Interpreter","none")
            legend(app.UIAxes,h2,"RMS")
            datacursormode(app.UIFigure,"on")
            app.d = datacursormode(app.UIFigure);
        end

        % Value changed function: SamplingFrequencyHzTextArea
        function SamplingFrequencyHzTextAreaValueChanged(app, event)
            value= app.SamplingFrequencyHzTextArea.Value;
            app.sampFreq = str2double(value);
        end

        % Value changed function: QindexTextArea
        function QindexTextAreaValueChanged(app, event)
            value = app.QindexTextArea.Value;
            app.QRSBegin = str2double(value);
        end

        % Value changed function: SindexTextArea
        function SindexTextAreaValueChanged(app, event)
            value = app.SindexTextArea.Value;
            app.QRSEnd = str2double(value);
            app.ATisnotprecomputedCheckBox.Enable = true;
            app.ATDropDown.Enable = true;
            app.NotificationTextArea.Value = "Choose activation time from file.";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           delete(app.UIFigure); %close the app
           HRL; %re-open it again
        end

        % Value changed function: ATisnotprecomputedCheckBox
        function ATisnotprecomputedCheckBoxValueChanged(app, event)
            app.AT_not_exist_val = app.ATisnotprecomputedCheckBox.Value;
             % activate AT Methods Dropdown

            if app.AT_not_exist_val == true
                % If checkbox is checked, make enable
                app.ATMethodsDropDown.Enable = true;
                app.ATMethodsDropDown.Items = ["min dV/dt","--None--"];
                app.ATMethodsDropDown.Value = "--None--";

            else
                % If checkbox is unchecked, make disable
                app.ATMethodsDropDown.Enable = false;

            end
            app.NotificationTextArea.Value = "If the geometry exists, check the box.";
        end

        % Value changed function: GeometryexistsCheckBox
        function GeometryexistsCheckBoxValueChanged(app, event)
            value = app.GeometryexistsCheckBox.Value;
            if value == true

                app.ATMethodsDropDown.Items = ["min dV/dt","Spatially Coherent","Spatio-Temporal","--None--"];
                app.ATMethodsDropDown.Value = "--None--";
                app.MaponGeometryButton.Enable = true;
                app.tg.Children(1).Parent = app.TabGroup;
            else
                % If checkbox is unchecked, make disable
                app.ATMethodsDropDown.Items = "min dV/dt";
                app.MaponGeometryButton.Enable = false;
            end
                 [file,path_2] = uigetfile('*.mat', 'Select a File');
             if isequal(file,0)
                 disp('User selected Cancel')
             else
                 app.geom_file = load(fullfile(path_2,file));
                 geom_tri = save_as_triangulation(app.geom_file);
                 app.geom = struct();
                 app.geom.pts = geom_tri.X;
                 app.geom.fac = geom_tri.Triangulation;         

             end
  
        end

        % Callback function
        function OverwriteButtonPushed(app, event)
                app.dict(app.valueAT) = num2cell(app.user_ATGUI,[1 2]);
                dictn = saveobj(app.dict);
                save(fullfile(app.path, 'dict'), 'dictn');
                app.NotificationsTextArea.Value = "Overwriting is successful!";
        end

        % Button pushed function: MaponGeometryButton
        function MaponGeometryButtonPushed2(app, event)
             app.TabGroup.SelectedTab = app.SpatialVisualizationTab;
             
             geom_tri = save_as_triangulation(app.geom_file);
             app.PTS = geom_tri.X;
             app.FAC = geom_tri.Triangulation;
             disp(sum(isnan(app.user_ATGUI)))
             AT_plot = app.user_ATGUI;
             AT_ms = (AT_plot./app.sampFreq)*1000;
             [~,Paceloc] = min(AT_plot);

             map_voltage_on_geom_app(app,app.PTS,app.FAC,AT_ms,'Activation Time Mapping',Paceloc);
             clim(app.UIAxes3,[min(AT_ms) max(AT_ms)])
             axis(app.UIAxes3,"off")
             c =colorbar(app.UIAxes3);
             c.Label.String = "Activation Time (ms)";
             c.AxisLocation = 'out';

             app.UIAxes3.OuterPosition(3) = 635;
        end

        % Value changed function: EarliestactivatedleadratioSlider
        function EarliestactivatedleadratioSliderValueChanged(app, event)
           value = round(app.EarliestactivatedleadratioSlider.Value);
           app.percent = value/100;
           app.TextArea.Value = num2str(value);
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.TemporalVisualizationTab; % open second tab automatically 
    
            app.fig = figure(app.UIFigure);
    
            app.plt = plot(app.UIAxes2,app.potvals(app.nodenum,:));
%             title(app.UIAxes2,"Heart potentials:",['Lead index:',num2str(app.nodenum)])
%     
%             plt1 = plot(app.UIAxes2_2,app.diff_potv(app.nodenum,:));
%             title(app.UIAxes2_2,"1^{st} order derivative:",['Lead index:',num2str(app.nodenum)])
%             datatip(plt1,"DataIndex",app.user_ATGUI(app.nodenum));
            hold(app.UIAxes2,"on")
            h_QRS = plot(app.UIAxes2,app.QRSBegin:app.QRSEnd,app.potvals(app.nodenum,app.QRSBegin:app.QRSEnd),"LineWidth",2,"Color","red");
            legend(app.UIAxes2,h_QRS,"QRS Segment")
            title(app.UIAxes2,"Heart potentials:",['Lead index:',num2str(app.nodenum)])
            hold(app.UIAxes2,"off")

            plt1 = plot(app.UIAxes2_2,app.QRSBegin:app.QRSEnd,app.diff_potv(app.nodenum,app.QRSBegin:app.QRSEnd));
            title(app.UIAxes2_2,"1^{st} order derivative in QRS segment:",['Lead index:',num2str(app.nodenum)])
            datatip(plt1,"DataIndex",app.user_ATGUI(app.nodenum));
            if (app.ShowButton.Value == 1)
    
                while true
                    %show the datatip selected in the derivative of potvals
                    %on potval plot
                    datacursormode (app.UIFigure, 'on');
                    dcm_obj = datacursormode(app.fig);
                    set(dcm_obj,'UpdateFcn',@myupdatefcn)
                    app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                    pause 
                    info_struct_1 = getCursorInfo(dcm_obj);
                    info_struct = info_struct_1(1);
                    DataIndex = info_struct.DataIndex;
                    dcm_obj.removeAllDataCursors();
                    datatip(app.plt,"DataIndex",DataIndex+app.QRSBegin)                            
                    delete(app.xline_handle)
                    app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                    if (app.SaveButton.Value == 1)
                        app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                        corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                        app.user_ATGUI(app.nodenum) = corrector_AT;
                      
                        break
                    elseif(app.DefectiveButton.Value == 1)
                        app.user_ATGUI(app.nodenum) = NaN;
                        app.Badlead(end+1) = app.nodenum;
                        break
                    else 
                        continue
                    end
                 end
                

            elseif (app.SaveButton.Value == 1)

                %save selected datatip
                datacursormode (app.UIFigure, 'on')
                dcm_obj = datacursormode(app.fig);
                % Set update function
                set(dcm_obj,'UpdateFcn',@myupdatefcn)
                app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                pause 
                
                if (app.ShowButton.Value == 1)

                    while true
                        %show the datatip selected in the derivative of potvals
                        %on potval plot
                        datacursormode (app.UIFigure, 'on')
                        dcm_obj = datacursormode(app.fig);
                        set(dcm_obj,'UpdateFcn',@myupdatefcn)
                        app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
                        pause 
                        info_struct_1 = getCursorInfo(dcm_obj);
                        info_struct = info_struct_1(1);
                        dcm_obj.removeAllDataCursors();
                        DataIndex = info_struct.DataIndex;
                        dcm_obj.removeAllDataCursors();
                        datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                        delete(app.xline_handle)
                        app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                        if (app.SaveButton.Value == 1)
                            app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
                            corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                            app.user_ATGUI(app.nodenum) = corrector_AT;
                            break
                        elseif(app.DefectiveButton.Value == 1)
                            app.user_ATGUI(app.nodenum) = NaN;
                            app.Badlead(end+1) = app.nodenum;
  
                            break
                        else 
                            continue
                        end
                    end
                 elseif(app.DefectiveButton.Value == 1)
             
                    pause
                    

                else
                    info_struct_1 = getCursorInfo(dcm_obj);
                    info_struct = info_struct_1(1);
                    dcm_obj.removeAllDataCursors();
                    DataIndex = info_struct.DataIndex;
                    corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                    app.user_ATGUI(app.nodenum) = corrector_AT;
                end

             elseif (app.DefectiveButton.Value == 1)

                datacursormode (app.UIFigure, 'on')
                dcm_obj = datacursormode(app.fig);
          
                set(dcm_obj,'UpdateFcn',@myupdatefcn)
          
                pause 
                
                if (app.ShowButton.Value == 1)

                    while true
                        %show the datatip selected in the derivative of potvals
                        %on potval plot
                        datacursormode (app.UIFigure, 'on')
                        dcm_obj = datacursormode(app.fig);
                        set(dcm_obj,'UpdateFcn',@myupdatefcn)
                        %disp('Click line to display a data tip, then press "Return"')
                        pause 
                        info_struct_1 = getCursorInfo(dcm_obj);
                        info_struct = info_struct_1(1);
                        DataIndex = info_struct.DataIndex;
                        dcm_obj.removeAllDataCursors();
                        datatip(app.plt,"DataIndex",DataIndex+1+app.QRSBegin)
                        delete(app.xline_handle)
                        app.xline_handle = xline(app.UIAxes2,DataIndex+app.QRSBegin,"LineWidth",2,"Color","g","DisplayName",'Activation Time');
                        if (app.SaveButton.Value == 1)
                            corrector_AT = ATcorrector(app.QRSBegin,app.QRSEnd,DataIndex+1);
                            app.user_ATGUI(app.nodenum) = corrector_AT;

                            break
                        elseif(app.DefectiveButton.Value == 1)
                            app.user_ATGUI(app.nodenum) = NaN;
                            app.Badlead(end+1) = app.nodenum;

                            break
                        else 
                            continue
                        end
                     end
                elseif(app.SaveButton.Value)
                
                    pause
        

                else
                    app.user_ATGUI(app.nodenum) = NaN;
                    app.Badlead(end+1) = app.nodenum;

                end
            end
            userATGUI = saveobj(app.user_ATGUI);
            save(fullfile(app.path, 'user_ATGUI'), 'userATGUI');
            app.dict("User_ATGUI") = num2cell(app.user_ATGUI,[1 2]);
            dictn = saveobj(app.dict);
            save(fullfile(app.path, 'dict'), 'dictn');
            app.NotificationsTextArea.Value = "If you want to overwrite the AT values, push the 'overwrite' button!";
            badlead = saveobj(app.Badlead);
            save(fullfile(app.path, 'badlead'), 'badlead');
    
%             if (app.ShowButton.Value == 1)
%     
%                 while true
%                     %show the datatip selected in the derivative of potvals
%                     %on potval plot
%                     datacursormode (app.UIFigure, 'on');
%                     dcm_obj = datacursormode(app.fig);
%                     set(dcm_obj,'UpdateFcn',@myupdatefcn)
%                     app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
%                     pause 
%                     info_struct_1 = getCursorInfo(dcm_obj);
%                     info_struct = info_struct_1(1);
%                     DataIndex = info_struct.DataIndex;
%                     dcm_obj.removeAllDataCursors();
%     %                             dt = findobj(app.UIAxes2,"Type","DataTip");
%     %                             dt1 = findobj(app.UIAxes2,"Type","DataTip");
%     %                             app.plt.DataTipTemplate = [];
%     %                             plt1.DataTipTemplate = [];
%     %                             delete(dt)
%     %                             delete(dt1)
%                     datatip(app.plt,"DataIndex",DataIndex+1)
%                     if (app.SaveButton.Value == 1)
%                         app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
%                         app.user_ATGUI(app.nodenum) = DataIndex+1;
% 
%                         break
%                     else 
%                         continue
%                     end
%                  end
%                 
%     
%             elseif (app.SaveButton.Value == 1)
%     
%                 %save selected datatip
%                 datacursormode (app.UIFigure, 'on')
%                 dcm_obj = datacursormode(app.fig);
%                 % Set update function
%                 set(dcm_obj,'UpdateFcn',@myupdatefcn)
%                 app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
%                 pause 
%                 info_struct = getCursorInfo(dcm_obj);
%                 app.user_ATGUI(app.nodenum) = info_struct.DataIndex;
%                 
%                 if (app.ShowButton.Value == 1)
%     
%                     while true
%                         %show the datatip selected in the derivative of potvals
%                         %on potval plot
%                         datacursormode (app.UIFigure, 'on')
%                         dcm_obj = datacursormode(app.fig);
%                         set(dcm_obj,'UpdateFcn',@myupdatefcn)
%                         app.NotificationsTextArea.Value = 'Press any key to display the datatip. If you want to save the data tip, click Save button.';
%                         pause 
%                         info_struct_1 = getCursorInfo(dcm_obj);
%                         info_struct = info_struct_1(1);
%                         dcm_obj.removeAllDataCursors();
%                         datatip(app.plt,"DataIndex",info_struct.DataIndex)
%                         if (app.SaveButton.Value == 1)
%                             app.NotificationsTextArea.Value = 'Press any key to save the datatip.';
%                             app.user_ATGUI(app.nodenum) = info_struct.DataIndex;
%                             break
%                         else 
%                             continue
%                         end
%                      end
%                 end      
%              end

        end

        % Value changed function: NodeNumberTextArea
        function NodeNumberTextAreaValueChanged(app, event)
            value = app.NodeNumberTextArea.Value;
            app.nodenum = str2double(value);
        end

        % Button pushed function: OverwriteButton
        function OverwriteButtonPushed2(app, event)
            correctedAT = app.user_ATGUI;
            command_str = "app.L";

            if app.ATisnotprecomputedCheckBox.Value ~= true

                name_list = split(app.valueAT,'->');
    
                for i = 1:length(name_list(1:end-1))
                    command_str = command_str + "." + name_list{i};
                end
                ATLoc = command_str;

                AT_saver = ATLoc + ".correctedAT_" + app.ATMethod + " = correctedAT";
                eval(AT_saver);

                QRS_saver = ATLoc + ".QRSBegin = app.QRSBegin";
                eval(QRS_saver);

                save_file = app.L;    
                save(fullfile(app.path, app.filename), '-struct',"save_file");
    
                badlead = app.Badlead;
                command_str2 = "app.L";
                command_str2 = command_str2 +".badlead = badlead";
                eval(command_str2)
                save_file2 = app.L;
                save(fullfile(app.path, app.filename), '-struct',"save_file2");
                msgbox("Overwriting is successful!")
            else

                
                AT_saver = command_str + ".correctedAT_" + app.ATMethod + " = correctedAT";
                eval(AT_saver);
                
                save_file = app.L;    
                save(fullfile(app.path, app.filename), '-struct',"save_file");

                QRS_saver = command_str + ".QRSBegin = app.QRSBegin";
                eval(QRS_saver);
                save_file2 = app.L;
                save(fullfile(app.path, app.filename), '-struct',"save_file2");
                
                msgbox("Overwriting is successful!")
            end

        end

        % Button pushed function: OverwriteButton_2
        function OverwriteButton_2Pushed(app, event)
            command_str = "app.L";

            if app.ATisnotprecomputedCheckBox.Value ~= true

                name_list = split(app.valueAT,'->');
    
                for i = 1:length(name_list(1:end-1))
                    command_str = command_str + "." + name_list{i};
                end
                ATLoc = command_str;

                AT_saver = ATLoc + ".correctedAT = correctedAT";
                eval(AT_saver);

                QRS_saver = ATLoc + ".QRSBegin = app.QRSBegin";
                eval(QRS_saver);

                save_file = app.L;    
                save(fullfile(app.path, app.filename), '-struct',"save_file");
    
                badlead = app.Badlead;
                command_str2 = "app.L";
                command_str2 = command_str2 +".badlead = badlead";
                eval(command_str2)
                save_file2 = app.L;
                save(fullfile(app.path, app.filename), '-struct',"save_file2");
                msgbox("Overwriting is successful!")
            else
                
                AT_saver = command_str + ".correctedAT = correctedAT";
                eval(AT_saver);

                QRS_saver = command_str + ".QRSBegin = app.QRSBegin";
                eval(QRS_saver);
            end
        end

        % Value changed function: ATMethodsDropDown
        function ATMethodsDropDownValueChanged(app, event)
            value = app.ATMethodsDropDown.Value;
            
        end

        % Drop down opening function: ATDropDown
        function ATDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.UIAxes = uiaxes(app.ParametersTab);
            title(app.UIAxes, 'Potentials')
            xlabel(app.UIAxes, 'time (sample)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.ParametersTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.ParametersTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ATDropDownLabel = uilabel(app.ParametersTab);
            app.ATDropDownLabel.Text = 'AT';
            app.ATDropDown = uidropdown(app.ParametersTab);
            app.ATDropDown.Items = {};
            app.ATDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ATDropDownOpening, true);
            app.ATDropDown.ValueChangedFcn = createCallbackFcn(app, @ATDropDownValueChanged, true);
            app.ATDropDown.Value = {};
            app.EpicardialPotentialsDropDownLabel = uilabel(app.ParametersTab);
            app.EpicardialPotentialsDropDownLabel.Text = {'Epicardial'; 'Potentials'};
            app.EpicardialPotentialsDropDown = uidropdown(app.ParametersTab);
            app.EpicardialPotentialsDropDown.Items = {};
            app.EpicardialPotentialsDropDown.ValueChangedFcn = createCallbackFcn(app, @EpicardialPotentialsDropDownValueChanged, true);
            app.EpicardialPotentialsDropDown.Value = {};
            app.SamplingFrequencyHzLabel = uilabel(app.ParametersTab);
            app.SamplingFrequencyHzLabel.Text = {'Sampling Frequency '; '(Hz)'};
            app.SamplingFrequencyHzTextArea = uitextarea(app.ParametersTab);
            app.SamplingFrequencyHzTextArea.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzTextAreaValueChanged, true);
            app.SamplingFrequencyHzTextArea.Value = {'1000'};
            app.PlotButton = uibutton(app.ParametersTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.QindexTextAreaLabel = uilabel(app.ParametersTab);
            app.QindexTextAreaLabel.Text = 'Q index';
            app.QindexTextArea = uitextarea(app.ParametersTab);
            app.QindexTextArea.ValueChangedFcn = createCallbackFcn(app, @QindexTextAreaValueChanged, true);
            app.SindexTextAreaLabel = uilabel(app.ParametersTab);
            app.SindexTextAreaLabel.Text = 'S index';
            app.SindexTextArea = uitextarea(app.ParametersTab);
            app.SindexTextArea.ValueChangedFcn = createCallbackFcn(app, @SindexTextAreaValueChanged, true);
            app.ATMethodsDropDownLabel = uilabel(app.ParametersTab);
            app.ATMethodsDropDownLabel.Text = 'AT Methods';
            app.ATMethodsDropDown = uidropdown(app.ParametersTab);
            app.ATMethodsDropDown.Items = {};
            app.ATMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @ATMethodsDropDownValueChanged, true);
            app.ATMethodsDropDown.Value = {};
            app.ATisnotprecomputedCheckBox = uicheckbox(app.ParametersTab);
            app.ATisnotprecomputedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ATisnotprecomputedCheckBoxValueChanged, true);
            app.ATisnotprecomputedCheckBox.Text = 'AT is not precomputed';
            app.GeometryexistsCheckBox = uicheckbox(app.ParametersTab);
            app.GeometryexistsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GeometryexistsCheckBoxValueChanged, true);
            app.GeometryexistsCheckBox.Text = 'Geometry exists';
            app.NotificationTextArea = uitextarea(app.ParametersTab);
            app.EarliestactivatedleadratioSliderLabel = uilabel(app.ParametersTab);
            app.EarliestactivatedleadratioSliderLabel.Text = {'Earliest activated'; 'lead ratio (%)'};
            app.EarliestactivatedleadratioSlider = uislider(app.ParametersTab);
            app.EarliestactivatedleadratioSlider.ValueChangedFcn = createCallbackFcn(app, @EarliestactivatedleadratioSliderValueChanged, true);
            app.TextArea = uitextarea(app.ParametersTab);
            app.TemporalVisualizationTab = uitab(app.TabGroup);
            app.TemporalVisualizationTab.Title = 'Temporal Visualization';
            app.UIAxes2 = uiaxes(app.TemporalVisualizationTab);
            title(app.UIAxes2, 'Heart potentials')
            xlabel(app.UIAxes2, 'time (sample)')
            ylabel(app.UIAxes2, 'Voltage (mv)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.TemporalVisualizationTab);
            title(app.UIAxes2_2, '1^{st} order derivative in QRS segment')
            xlabel(app.UIAxes2_2, 'time (sample)')
            ylabel(app.UIAxes2_2, 'Voltage/sample')
            zlabel(app.UIAxes2_2, 'Z')
            app.ButtonGroup = uibuttongroup(app.TemporalVisualizationTab);
            app.ShowButton = uiradiobutton(app.ButtonGroup);
            app.ShowButton.Text = 'Show';
            app.ShowButton.Value = true;
            app.SaveButton = uiradiobutton(app.ButtonGroup);
            app.SaveButton.Text = 'Save';
            app.DefectiveButton = uiradiobutton(app.ButtonGroup);
            app.DefectiveButton.Text = 'Defective';
            app.NotificationsTextArea = uitextarea(app.TemporalVisualizationTab);
            app.MaponGeometryButton = uibutton(app.TemporalVisualizationTab, 'push');
            app.MaponGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @MaponGeometryButtonPushed2, true);
            app.MaponGeometryButton.Text = 'Map on Geometry';
            app.OverwriteButton = uibutton(app.TemporalVisualizationTab, 'push');
            app.OverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteButtonPushed2, true);
            app.OverwriteButton.Text = 'Overwrite';
            app.SpatialVisualizationTab = uitab(app.TabGroup);
            app.SpatialVisualizationTab.Title = 'Spatial Visualization';
            app.UIAxes3 = uiaxes(app.SpatialVisualizationTab);
            title(app.UIAxes3, 'Activation Time Mapping')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.PlotButton_2 = uibutton(app.SpatialVisualizationTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.NodeNumberTextAreaLabel = uilabel(app.SpatialVisualizationTab);
            app.NodeNumberTextAreaLabel.Text = 'Node Number';
            app.NodeNumberTextArea = uitextarea(app.SpatialVisualizationTab);
            app.NodeNumberTextArea.ValueChangedFcn = createCallbackFcn(app, @NodeNumberTextAreaValueChanged, true);
            app.OverwriteButton_2 = uibutton(app.SpatialVisualizationTab, 'push');
            app.OverwriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @OverwriteButton_2Pushed, true);
            app.OverwriteButton_2.Text = 'Overwrite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_try

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end